classdef ABMPlanting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnvironmentDropDown             matlab.ui.control.DropDown
        EnvironmentDropDownLabel        matlab.ui.control.Label
        StarttheModelButton             matlab.ui.control.Button
        ParaminfluencingtheaccuLabel_3  matlab.ui.control.Label
        ParaminfluencingtheaccuLabel_2  matlab.ui.control.Label
        ParaminfluencingtheaccuLabel    matlab.ui.control.Label
        UpperlimitforresourcesLabel     matlab.ui.control.Label
        UpperlimitformetabolismLabel    matlab.ui.control.Label
        UpperlimitforvisionLabel        matlab.ui.control.Label
        NumberofiterationsLabel         matlab.ui.control.Label
        NumberofgeneratedagentsLabel    matlab.ui.control.Label
        DimensionsofthegridLabel        matlab.ui.control.Label
        PlantingSwitch                  matlab.ui.control.Switch
        PlantingSwitchLabel             matlab.ui.control.Label
        SteepnessSlider                 matlab.ui.control.Slider
        SteepnessSliderLabel            matlab.ui.control.Label
        UsageSlider                     matlab.ui.control.Slider
        UsageSlider_2Label              matlab.ui.control.Label
        ReplenishingSlider              matlab.ui.control.Slider
        ReplenishingSliderLabel         matlab.ui.control.Label
        MaxresourcesEditField           matlab.ui.control.NumericEditField
        MaxresourcesEditFieldLabel      matlab.ui.control.Label
        MaxmetablismEditField           matlab.ui.control.NumericEditField
        MaxmetablismEditFieldLabel      matlab.ui.control.Label
        MaxvisionEditField              matlab.ui.control.NumericEditField
        MaxvisionEditFieldLabel         matlab.ui.control.Label
        NrunsEditField                  matlab.ui.control.NumericEditField
        NrunsEditFieldLabel             matlab.ui.control.Label
        NAgentsEditField                matlab.ui.control.NumericEditField
        NAgentsEditFieldLabel           matlab.ui.control.Label
        GridSizeEditField               matlab.ui.control.NumericEditField
        GridSizeEditFieldLabel          matlab.ui.control.Label
        PlantingaTreeagentbasedmodelLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        % Display the agent locations in the app's UIAxes
        function dispagentlocapp(app, s, gridSize, grid, upperbound)
            
            mymap = [0.8667 0.6314 0.3686;
                     0.7373 0.4235 0.1451;
                     0.3765 0.4235 0.2196;
                     0.2196 0.4 0.2549;
                     0.4157 0.6 0.3059;
                     0.6549 0.7882 0.3412];
        
            % Plotting in the app's UIAxes
            axes(app.UIAxes);
            imagesc(app.UIAxes, reshape([s.maxcapacity], gridSize, gridSize));
            colormap(app.UIAxes, mymap);
            colorbar(app.UIAxes, 'Color', [1, 1, 1]);
            clim(app.UIAxes, [0, upperbound]);
        
            axis(app.UIAxes, 'square');
            hold(app.UIAxes, 'on');
        
            % Overlay the grid (spy function does not work in AppDesigner)
            [row, col] = find(grid);
            plot(app.UIAxes, row, col, 'marker', '.', 'markersize', 10,'linestyle', 'none', 'Color', 'k');
            hold(app.UIAxes, 'off');
            drawnow; % Force immediate UI update
            
        end
        
        function dispwealthapp(app, agents)
            % The function displays the last wealth values of active agents as a histogram.
        
            numActiveAgents = sum([agents.active] == 1);
            wealthValues = zeros(1, numActiveAgents);
        
            % Loop through each active agent and find each present wealth value
            id = 1;
            for i = 1:length(agents)
                if agents(i).active == 1
                    wealthValues(id) = agents(i).getLastWealth();
                    id = id + 1;
                end
            end
        
            % Create a new figure window for displaying the histogram of wealth values
            axes(app.UIAxes_2);
            histogram(app.UIAxes_2, wealthValues, 'FaceColor', 'k');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StarttheModelButton
        function StarttheModelButtonPushed(app, event)
            gridSize = app.GridSizeEditField.Value;
            numAgents = app.NAgentsEditField.Value;
            visionmax = app.MaxvisionEditField.Value;
            metabolismmax = app.MaxmetablismEditField.Value;
            maxsugar = app.MaxresourcesEditField.Value;
            accumulation_rule = round(app.ReplenishingSlider.Value, 2);
            resource_steepness = round(app.SteepnessSlider.Value, 2);
            resource_usage = round(app.UsageSlider.Value, 2);
            nruns = app.NrunsEditField.Value;
            model_type = string(app.PlantingSwitch.Value);
            env_type = string(app.EnvironmentDropDown.Value);
            upperbound = maxsugar / 2;

            switch model_type
                case "On"
                    planting = true;
                case "Off"
                    planting = false;
            end

            switch env_type
                case "Circular"
                    s = initcircularsugar(gridSize, maxsugar, resource_steepness);
                case "Steep"
                    s = initsugarscape(gridSize, maxsugar);
            end

            % Main loop
            
            [agents, grid] = initagents(gridSize, s, visionmax, metabolismmax, numAgents);

            for runs = 1:nruns
                dispagentlocapp(app, s, gridSize, grid, upperbound);
                dispwealthapp(app, agents);
                s = updates(s, gridSize, accumulation_rule);
            
                for idx = randperm(length(agents))
                    agent = agents(idx);
                    if agent.active == 1
                        
                        % Agent explores sugarscape in random directions and selects the best location
                        temps = s(agent.position(1), agent.position(2));
                        tempi = agent.position(1);
                        tempj = agent.position(2);
                        
                        for k = agent.vision : -1 : 1
                            [agents(idx), temps, tempi, tempj] = observe(agents(idx), k, grid, s, gridSize, temps, tempi, tempj);
                        end
                        
                        % Agent moves to best location, updates sugar stock and eats sugar
                        [agents(idx), grid, s] = moveagent(agents(idx), s, tempi, tempj, temps, grid, planting, resource_usage);
            
                        % Agent observes new environment
                        new_env = zeros(2*agents(idx).vision+1);
                        for k = agent.vision : -1 : 1
                            [agents(idx), new_env] = observenew(agents(idx), new_env, k, s, gridSize);
                        end
            
                        % Find the difference between before and now
                        diff_env = new_env - agents(idx).visionGrid;
                        agents(idx).visionGrid = double(diff_env >0);
                       
            
                        % Update agent's probability of planting a tree
                        N = agents(idx).vision * 4;
                        X = sum(agents(idx).visionGrid, 'all');
                        agents(idx) = agents(idx).updatePlantingProb(N, X);
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            app.UIAxes.CLim = [0 100];
            app.UIAxes.ALim = [0 100];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'Wealth')
            ylabel(app.UIAxes_2, 'Agents')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.CLim = [0 100];
            app.UIAxes_2.ALim = [0 100];
            app.PlantingaTreeagentbasedmodelLabel = uilabel(app.UIFigure);
            app.PlantingaTreeagentbasedmodelLabel.Text = 'Planting a Tree: agent-based model';
            app.GridSizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridSizeEditFieldLabel.Text = 'Grid Size';
            app.GridSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridSizeEditField.Value = 100;
            app.NAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NAgentsEditFieldLabel.Text = 'N. Agents';
            app.NAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NAgentsEditField.Value = 1000;
            app.NrunsEditFieldLabel = uilabel(app.UIFigure);
            app.NrunsEditFieldLabel.Text = 'N. runs';
            app.NrunsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NrunsEditField.Value = 16;
            app.MaxvisionEditFieldLabel = uilabel(app.UIFigure);
            app.MaxvisionEditFieldLabel.Text = 'Max vision';
            app.MaxvisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxvisionEditField.Value = 5;
            app.MaxmetablismEditFieldLabel = uilabel(app.UIFigure);
            app.MaxmetablismEditFieldLabel.Text = 'Max metablism';
            app.MaxmetablismEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxmetablismEditField.Value = 5;
            app.MaxresourcesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxresourcesEditFieldLabel.Text = 'Max resources';
            app.MaxresourcesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxresourcesEditField.Value = 20;
            app.ReplenishingSliderLabel = uilabel(app.UIFigure);
            app.ReplenishingSliderLabel.Text = 'Replenishing';
            app.ReplenishingSlider = uislider(app.UIFigure);
            app.ReplenishingSlider.Limits = [0 1];
            app.ReplenishingSlider.MajorTicks = [0 0.5 1];
            app.ReplenishingSlider.Value = 0.5;
            app.UsageSlider_2Label = uilabel(app.UIFigure);
            app.UsageSlider_2Label.Text = 'Usage';
            app.UsageSlider = uislider(app.UIFigure);
            app.UsageSlider.Limits = [0 1];
            app.UsageSlider.MajorTicks = [0 0.5 1];
            app.UsageSlider.Value = 0.9;
            app.SteepnessSliderLabel = uilabel(app.UIFigure);
            app.SteepnessSliderLabel.Text = 'Steepness';
            app.SteepnessSlider = uislider(app.UIFigure);
            app.SteepnessSlider.Limits = [0 3];
            app.SteepnessSlider.MajorTicks = [0 1 2 3];
            app.SteepnessSlider.Value = 1.5;
            app.PlantingSwitchLabel = uilabel(app.UIFigure);
            app.PlantingSwitchLabel.Text = 'Planting';
            app.PlantingSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlantingSwitch.Value = 'On';
            app.DimensionsofthegridLabel = uilabel(app.UIFigure);
            app.DimensionsofthegridLabel.Text = 'Dimensions of the grid';
            app.NumberofgeneratedagentsLabel = uilabel(app.UIFigure);
            app.NumberofgeneratedagentsLabel.Text = 'Number of generated agents';
            app.NumberofiterationsLabel = uilabel(app.UIFigure);
            app.NumberofiterationsLabel.Text = 'Number of iterations';
            app.UpperlimitforvisionLabel = uilabel(app.UIFigure);
            app.UpperlimitforvisionLabel.Text = 'Upper limit for vision';
            app.UpperlimitformetabolismLabel = uilabel(app.UIFigure);
            app.UpperlimitformetabolismLabel.Text = 'Upper limit for metabolism';
            app.UpperlimitforresourcesLabel = uilabel(app.UIFigure);
            app.UpperlimitforresourcesLabel.Text = 'Upper limit for resources';
            app.ParaminfluencingtheaccuLabel = uilabel(app.UIFigure);
            app.ParaminfluencingtheaccuLabel.Text = 'Param influencing the accumulation of resources in the environment: higher means faster regrowth';
            app.ParaminfluencingtheaccuLabel_2 = uilabel(app.UIFigure);
            app.ParaminfluencingtheaccuLabel_2.Text = 'Param influencing the agent''s usage or resources: higher means less resources for future agents';
            app.ParaminfluencingtheaccuLabel_3 = uilabel(app.UIFigure);
            app.ParaminfluencingtheaccuLabel_3.Text = 'Param influencing the shape of the environment: higher means steeper peaks and deeper valleys';
            app.StarttheModelButton = uibutton(app.UIFigure, 'push');
            app.StarttheModelButton.ButtonPushedFcn = createCallbackFcn(app, @StarttheModelButtonPushed, true);
            app.StarttheModelButton.Text = 'Start the Model';
            app.EnvironmentDropDownLabel = uilabel(app.UIFigure);
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.UIFigure);
            app.EnvironmentDropDown.Items = {'Circular', 'Steep'};
            app.EnvironmentDropDown.Value = 'Circular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABMPlanting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end